classdef GUItest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StartSimulationLabel      matlab.ui.control.Label
        ChooseADrinkToMakeLabel   matlab.ui.control.Label
        BarSimulationLabel        matlab.ui.control.Label
        EmergencyStopButton       matlab.ui.control.Button
        ResetESTOPandRobotButton  matlab.ui.control.Button
        LoadEnvironmentButton     matlab.ui.control.Button
        Drink1Button              matlab.ui.control.Button
        Drink2Button              matlab.ui.control.Button
    end

    
    properties (Access = public)
        estopTimer 
        buttonPressed = false;
        a
        state
        isEmergencyStopTriggered = false;
        currentQ
    end
    
    
    methods (Access = private)
        
        function checkEmergencyStop(app)
        % function to check if the estop is pressed

        % Read the state of the emergency stop button from Arduino
        app.state = readDigitalPin(app.a, 'D2');
            
            % If either GUI or Arduino ESTOP button is pressed
            if (app.EmergencyStopButton.UserData) || (app.state && ~app.buttonPressed)
                % Update logic statements
                app.isEmergencyStopTriggered = true;
                app.buttonPressed = true;
                % Trigger the Emergency Stop callback
                EmergencyStopButtonPushed(app);
        
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Drink1Button
        function Drink1ButtonPushed(app, event)
            % Disable buttons to prevent multiple clicks
            app.Drink1Button.Enable = 'off';
            app.Drink2Button.Enable = 'off';
            app.EmergencyStopButton.Enable = 'on';
         
            % Start the timer that checks if estop is pressed
            start(app.estopTimer);

            % Run function code for drink 1
            %Movement(app); % Change to actual code (this is test code)
            
            % stop the timer
            stop(app.estopTimer);

            % if the estop is not pressed, reset buttons
            if app.EmergencyStopButton.UserData == false
                % Re-enable buttons
                app.Drink1Button.Enable = 'on';
                app.Drink2Button.Enable = 'on';
                app.EmergencyStopButton.Enable = 'off';
            end  
        end

        % Button pushed function: Drink2Button
        function Drink2ButtonPushed(app, event)
            % Disable buttons to prevent multiple clicks
            app.Drink1Button.Enable = 'off';
            app.Drink2Button.Enable = 'off';
            app.EmergencyStopButton.Enable = 'on';
          
            % Start the timer that checks if estop is pressed
            start(app.estopTimer);

            % Run function code for drink 2
            %Movement(app); % Change to actual code (this is test code)
        
            % stop the timer
            stop(app.estopTimer);
    
            % if the estop is not pressed, reset buttons
            if app.EmergencyStopButton.UserData == false
                % Re-enable buttons
                app.Drink1Button.Enable = 'on';
                app.Drink2Button.Enable = 'on';
                app.EmergencyStopButton.Enable = 'off';
            end  
        end

        % Button pushed function: LoadEnvironmentButton
        function LoadEnvironmentButtonPushed(app, event)
            % Turn off load environment button
            % only pressed once at the start
            app.LoadEnvironmentButton.Enable = 'off';
          
            % Create an instance of the Assignment2 class
            app = Assignment2();
            
            % Load environment
            app = app.initialiseScene();
            
            % Set up arduino 
            clear app.a;
            app.a = arduino();

            % Create a timer to constantly check estop
    app.estopTimer = timer('ExecutionMode', 'fixedRate', ...
                           'Period', 0.1, ... % Check every 0.1 seconds
                           'TimerFcn', @(~,~) checkEmergencyStop(app));
            disp('finished');
            % Re-enable buttons after function is complete
            app.Drink1Button.Enable = 'on';
            app.Drink2Button.Enable = 'on';
            app.EmergencyStopButton.UserData = false;
        end

        % Button pushed function: ResetESTOPandRobotButton
        function ResetESTOPandRobotButtonPushed(app, event)
            app.ResetESTOPandRobotButton.Enable = 'off';
            
            % code to reset robot to initial starting position 
            %ReturnRobot(app); % Change to actual code (this is test code)
              
            app.Drink1Button.Enable = 'on';
            app.Drink2Button.Enable = 'on';
            app.EmergencyStopButton.UserData = false;
            app.buttonPressed = false;
            app.ResetESTOPandRobotButton.Enable = 'off';
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            app.EmergencyStopButton.UserData = true;
            app.Drink1Button.Enable = 'off';
            app.Drink2Button.Enable = 'off';
            
            % code that stops robot
            
            app.ResetESTOPandRobotButton.Enable = 'on';
            app.EmergencyStopButton.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Drink2Button = uibutton(app.UIFigure, 'push');
            app.Drink2Button.ButtonPushedFcn = createCallbackFcn(app, @Drink2ButtonPushed, true);
            app.Drink2Button.Enable = 'off';
            app.Drink2Button.Text = 'Drink2';
            app.Drink1Button = uibutton(app.UIFigure, 'push');
            app.Drink1Button.ButtonPushedFcn = createCallbackFcn(app, @Drink1ButtonPushed, true);
            app.Drink1Button.Enable = 'off';
            app.Drink1Button.Text = 'Drink1';
            app.LoadEnvironmentButton = uibutton(app.UIFigure, 'push');
            app.LoadEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEnvironmentButtonPushed, true);
            app.LoadEnvironmentButton.Text = 'Load Environment';
            app.ResetESTOPandRobotButton = uibutton(app.UIFigure, 'push');
            app.ResetESTOPandRobotButton.ButtonPushedFcn = createCallbackFcn(app, @ResetESTOPandRobotButtonPushed, true);
            app.ResetESTOPandRobotButton.Enable = 'off';
            app.ResetESTOPandRobotButton.Text = 'Reset ESTOP and  Robot';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Enable = 'off';
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.BarSimulationLabel = uilabel(app.UIFigure);
            app.BarSimulationLabel.Text = 'Bar Simulation';
            app.ChooseADrinkToMakeLabel = uilabel(app.UIFigure);
            app.ChooseADrinkToMakeLabel.Text = 'Choose A Drink To Make';
            app.StartSimulationLabel = uilabel(app.UIFigure);
            app.StartSimulationLabel.Text = 'Start Simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUItest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end